classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        MPPTPanel                     matlab.ui.container.Panel
        MpptEfficiencyEditField       matlab.ui.control.EditField
        MpptEfficiencyEditFieldLabel  matlab.ui.control.Label
        MpptIoutEditField             matlab.ui.control.EditField
        MpptIoutEditFieldLabel        matlab.ui.control.Label
        MpptVoutEditField             matlab.ui.control.EditField
        MpptVoutEditFieldLabel        matlab.ui.control.Label
        MpptIinEditField              matlab.ui.control.EditField
        MpptIinEditFieldLabel         matlab.ui.control.Label
        MpptVinEditField              matlab.ui.control.EditField
        MpptVinEditFieldLabel         matlab.ui.control.Label
        STOPButton                    matlab.ui.control.Button
        Current2EditField             matlab.ui.control.EditField
        Current2EditFieldLabel        matlab.ui.control.Label
        BatteryVoltageEditField       matlab.ui.control.EditField
        BatteryVoltageEditFieldLabel  matlab.ui.control.Label
        Current1EditField             matlab.ui.control.EditField
        Current1EditFieldLabel        matlab.ui.control.Label
        STARTButton                   matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        flag =1;% Description
        h;
        s;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
   
               
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            %app.flag = 1;
            app.h = animatedline(app.UIAxes);


            ax = gca;
            ax.YGrid = 'on';
            ax.YLim = [0 500] ;
            startTime = datetime('now');

          while app.flag ==1  
            %[data1,data2,data3] = SerialTest;

            delete(instrfind({'Port'},{'COM3'}));
            app.s = serial("COM3");
            app.s.BaudRate = 115200;
            fopen(app.s);
            numval = fscanf(app.s);
            data = split(numval,",");
            data1= string(data(1));
            data2= string(data(2));
            data3= string(data(3));


            app.BatteryVoltageEditField.Value = data1;
            app.Current1EditField.Value = data2;
            app.Current2EditField.Value = data3;


            dat1 =double(data1);
            t = datetime('now') - startTime;
            addpoints(app.h,datenum(t), dat1);
            ax.XLim = datenum([t-seconds(15) t]);
            datetick('x','keeplimits');
            drawnow

            pause(5/1000);
          end
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            app.flag =0;
            %STARTButtonPushed(app, event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Battery')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.Current1EditFieldLabel = uilabel(app.UIFigure);
            app.Current1EditFieldLabel.Text = 'Current 1';
            app.Current1EditField = uieditfield(app.UIFigure, 'text');
            app.BatteryVoltageEditFieldLabel = uilabel(app.UIFigure);
            app.BatteryVoltageEditFieldLabel.Text = 'Battery Voltage';
            app.BatteryVoltageEditField = uieditfield(app.UIFigure, 'text');
            app.Current2EditFieldLabel = uilabel(app.UIFigure);
            app.Current2EditFieldLabel.Text = 'Current 2';
            app.Current2EditField = uieditfield(app.UIFigure, 'text');
            app.STOPButton = uibutton(app.UIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.MPPTPanel = uipanel(app.UIFigure);
            app.MPPTPanel.Title = 'MPPT';
            app.MpptVinEditFieldLabel = uilabel(app.MPPTPanel);
            app.MpptVinEditFieldLabel.Text = 'Mppt Vin';
            app.MpptVinEditField = uieditfield(app.MPPTPanel, 'text');
            app.MpptIinEditFieldLabel = uilabel(app.MPPTPanel);
            app.MpptIinEditFieldLabel.Text = 'Mppt Iin';
            app.MpptIinEditField = uieditfield(app.MPPTPanel, 'text');
            app.MpptVoutEditFieldLabel = uilabel(app.MPPTPanel);
            app.MpptVoutEditFieldLabel.Text = 'Mppt Vout';
            app.MpptVoutEditField = uieditfield(app.MPPTPanel, 'text');
            app.MpptIoutEditFieldLabel = uilabel(app.MPPTPanel);
            app.MpptIoutEditFieldLabel.Text = 'Mppt Iout';
            app.MpptIoutEditField = uieditfield(app.MPPTPanel, 'text');
            app.MpptEfficiencyEditFieldLabel = uilabel(app.MPPTPanel);
            app.MpptEfficiencyEditFieldLabel.Text = 'Mppt Efficiency';
            app.MpptEfficiencyEditField = uieditfield(app.MPPTPanel, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end